classdef tubes1gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        Nomor1Menu                matlab.ui.container.Menu
        Nomor2Menu                matlab.ui.container.Menu
        Menu3                     matlab.ui.container.Menu
        TransformasiLogPanel      matlab.ui.container.Panel
        GridLayout8_3             matlab.ui.container.GridLayout
        cEditField_2              matlab.ui.control.NumericEditField
        cEditField_2Label         matlab.ui.control.Label
        TransformasiPangkatPanel  matlab.ui.container.Panel
        GridLayout8_2             matlab.ui.container.GridLayout
        gEditField                matlab.ui.control.NumericEditField
        gEditFieldLabel           matlab.ui.control.Label
        cEditField                matlab.ui.control.NumericEditField
        cEditFieldLabel           matlab.ui.control.Label
        ImageBrighteningPanel     matlab.ui.container.Panel
        GridLayout8               matlab.ui.container.GridLayout
        bEditField                matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        aEditField                matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        InputImageRefrensiButton  matlab.ui.control.Button
        GridLayout5               matlab.ui.container.GridLayout
        GridLayout9               matlab.ui.container.GridLayout
        ProcessButton             matlab.ui.control.StateButton
        InputImageButton          matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        OriginalTab               matlab.ui.container.Tab
        GridLayout6               matlab.ui.container.GridLayout
        TabGroup2                 matlab.ui.container.TabGroup
        GrayTab                   matlab.ui.container.Tab
        GridLayout7               matlab.ui.container.GridLayout
        UIAxes                    matlab.ui.control.UIAxes
        RedTab                    matlab.ui.container.Tab
        GridLayout7_4             matlab.ui.container.GridLayout
        UIAxesR                   matlab.ui.control.UIAxes
        GreenTab                  matlab.ui.container.Tab
        GridLayout7_3             matlab.ui.container.GridLayout
        UIAxesG                   matlab.ui.control.UIAxes
        BlueTab                   matlab.ui.container.Tab
        GridLayout7_2             matlab.ui.container.GridLayout
        UIAxesB                   matlab.ui.control.UIAxes
        Image                     matlab.ui.control.Image
        CorrectionTab             matlab.ui.container.Tab
        GridLayout10              matlab.ui.container.GridLayout
        TabGroup3                 matlab.ui.container.TabGroup
        GrayTab_2                 matlab.ui.container.Tab
        GridLayout11              matlab.ui.container.GridLayout
        UIAxes3                   matlab.ui.control.UIAxes
        RedTab_2                  matlab.ui.container.Tab
        GridLayout11_2            matlab.ui.container.GridLayout
        UIAxes3_2                 matlab.ui.control.UIAxes
        GreenTab_2                matlab.ui.container.Tab
        GridLayout11_3            matlab.ui.container.GridLayout
        UIAxes3_3                 matlab.ui.control.UIAxes
        BlueTab_2                 matlab.ui.container.Tab
        GridLayout11_4            matlab.ui.container.GridLayout
        UIAxes3_4                 matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        selectedFile % To store the selected image file path
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageBrighteningPanel.Parent = [];
            app.CorrectionTab.Parent = [];
            app.TransformasiLogPanel.Parent = [];
            app.TransformasiPangkatPanel.Parent = [];
            app.InputImageRefrensiButton.Parent = [];
        end

        % Button pushed function: InputImageButton
        function ImageInputButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                app.selectedFile = fullfile(path, file);
                
                % Display the image in the app's UI Image component (app.Image)
                app.Image.ImageSource = app.selectedFile;
            end
        end

        % Value changed function: ProcessButton
        function ProcessButtonValueChanged(app, event)
            % Read the image
            img = imread(app.selectedFile);
            
            % Init classes
            imgprcs = ImageProcessor;

            % If the image is RGB, convert it to grayscale for histogram analysis
            if size(img, 3) == 3
                rgbHistogram = imgprcs.calculateColoredHistogram(img);
                
                % Plot red histogram
                bar(app.UIAxesR, rgbHistogram(1, :));
                title(app.UIAxesR, 'Image Red Histogram');
                xlabel(app.UIAxesR, 'Pixel Intensity');
                ylabel(app.UIAxesR, 'Frequency');
                
                % Plot green histogram
                bar(app.UIAxesG, rgbHistogram(2, :));
                title(app.UIAxesG, 'Image Green Histogram');
                xlabel(app.UIAxesG, 'Pixel Intensity');
                ylabel(app.UIAxesG, 'Frequency');
                
                % Plot blue histogram
                bar(app.UIAxesB, rgbHistogram(3, :));
                title(app.UIAxesB, 'Image Blue Histogram');
                xlabel(app.UIAxesB, 'Pixel Intensity');
                ylabel(app.UIAxesB, 'Frequency');
            else
                % Compute the histogram of the grayscale image
                histogram = imgprcs.calculateGrayscaledHistogram(img);
            
                % Plot the histogram in app.UIAxes
                bar(app.UIAxes, histogram);
                title(app.UIAxes, 'Image Gray Histogram');
                xlabel(app.UIAxes, 'Pixel Intensity');
                ylabel(app.UIAxes, 'Frequency');
            end
        end

        % Menu selected function: Nomor1Menu
        function Nomor1MenuSelected(app, event)
            app.ImageBrighteningPanel.Parent = [];
            app.TransformasiLogPanel.Parent = [];
            app.TransformasiPangkatPanel.Parent = [];
            app.CorrectionTab.Parent = [];
        end

        % Menu selected function: Nomor2Menu
        function Nomor2MenuSelected(app, event)
            app.CorrectionTab.Parent = app.TabGroup;
            app.ImageBrighteningPanel.Parent = app.GridLayout9;
            app.TransformasiLogPanel.Parent = app.GridLayout9;
            app.TransformasiPangkatPanel.Parent = app.GridLayout9;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Nomor1Menu = uimenu(app.Menu);
            app.Nomor1Menu.MenuSelectedFcn = createCallbackFcn(app, @Nomor1MenuSelected, true);
            app.Nomor1Menu.Text = 'Nomor 1';
            app.Nomor2Menu = uimenu(app.Menu);
            app.Nomor2Menu.MenuSelectedFcn = createCallbackFcn(app, @Nomor2MenuSelected, true);
            app.Nomor2Menu.Text = 'Nomor 2';
            app.Menu3 = uimenu(app.Menu);
            app.Menu3.Text = 'Menu3';
            app.GridLayout5 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout5);
            app.OriginalTab = uitab(app.TabGroup);
            app.OriginalTab.Title = 'Original';
            app.GridLayout6 = uigridlayout(app.OriginalTab);
            app.Image = uiimage(app.GridLayout6);
            app.TabGroup2 = uitabgroup(app.GridLayout6);
            app.GrayTab = uitab(app.TabGroup2);
            app.GrayTab.Title = 'Gray';
            app.GridLayout7 = uigridlayout(app.GrayTab);
            app.UIAxes = uiaxes(app.GridLayout7);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RedTab = uitab(app.TabGroup2);
            app.RedTab.Title = 'Red';
            app.GridLayout7_4 = uigridlayout(app.RedTab);
            app.UIAxesR = uiaxes(app.GridLayout7_4);
            title(app.UIAxesR, 'Title')
            xlabel(app.UIAxesR, 'X')
            ylabel(app.UIAxesR, 'Y')
            zlabel(app.UIAxesR, 'Z')
            app.GreenTab = uitab(app.TabGroup2);
            app.GreenTab.Title = 'Green';
            app.GridLayout7_3 = uigridlayout(app.GreenTab);
            app.UIAxesG = uiaxes(app.GridLayout7_3);
            title(app.UIAxesG, 'Title')
            xlabel(app.UIAxesG, 'X')
            ylabel(app.UIAxesG, 'Y')
            zlabel(app.UIAxesG, 'Z')
            app.BlueTab = uitab(app.TabGroup2);
            app.BlueTab.Title = 'Blue';
            app.GridLayout7_2 = uigridlayout(app.BlueTab);
            app.UIAxesB = uiaxes(app.GridLayout7_2);
            title(app.UIAxesB, 'Title')
            xlabel(app.UIAxesB, 'X')
            ylabel(app.UIAxesB, 'Y')
            zlabel(app.UIAxesB, 'Z')
            app.CorrectionTab = uitab(app.TabGroup);
            app.CorrectionTab.Title = 'Correction';
            app.GridLayout10 = uigridlayout(app.CorrectionTab);
            app.UIAxes2 = uiaxes(app.GridLayout10);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TabGroup3 = uitabgroup(app.GridLayout10);
            app.GrayTab_2 = uitab(app.TabGroup3);
            app.GrayTab_2.Title = 'Gray';
            app.GridLayout11 = uigridlayout(app.GrayTab_2);
            app.UIAxes3 = uiaxes(app.GridLayout11);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.RedTab_2 = uitab(app.TabGroup3);
            app.RedTab_2.Title = 'Red';
            app.GridLayout11_2 = uigridlayout(app.RedTab_2);
            app.UIAxes3_2 = uiaxes(app.GridLayout11_2);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.GreenTab_2 = uitab(app.TabGroup3);
            app.GreenTab_2.Title = 'Green';
            app.GridLayout11_3 = uigridlayout(app.GreenTab_2);
            app.UIAxes3_3 = uiaxes(app.GridLayout11_3);
            title(app.UIAxes3_3, 'Title')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.BlueTab_2 = uitab(app.TabGroup3);
            app.BlueTab_2.Title = 'Blue';
            app.GridLayout11_4 = uigridlayout(app.BlueTab_2);
            app.UIAxes3_4 = uiaxes(app.GridLayout11_4);
            title(app.UIAxes3_4, 'Title')
            xlabel(app.UIAxes3_4, 'X')
            ylabel(app.UIAxes3_4, 'Y')
            zlabel(app.UIAxes3_4, 'Z')
            app.InputImageButton = uibutton(app.GridLayout5, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageInputButtonPushed, true);
            app.InputImageButton.Text = 'Input Image';
            app.ProcessButton = uibutton(app.GridLayout5, 'state');
            app.ProcessButton.ValueChangedFcn = createCallbackFcn(app, @ProcessButtonValueChanged, true);
            app.ProcessButton.Text = 'Process';
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.InputImageRefrensiButton = uibutton(app.UIFigure, 'push');
            app.InputImageRefrensiButton.Text = 'Input Image Refrensi';
            app.ImageBrighteningPanel = uipanel(app.UIFigure);
            app.ImageBrighteningPanel.Title = 'Image Brightening';
            app.GridLayout8 = uigridlayout(app.ImageBrighteningPanel);
            app.aEditFieldLabel = uilabel(app.GridLayout8);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.GridLayout8, 'numeric');
            app.bEditFieldLabel = uilabel(app.GridLayout8);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.GridLayout8, 'numeric');
            app.TransformasiPangkatPanel = uipanel(app.UIFigure);
            app.TransformasiPangkatPanel.Title = 'Transformasi Pangkat';
            app.GridLayout8_2 = uigridlayout(app.TransformasiPangkatPanel);
            app.cEditFieldLabel = uilabel(app.GridLayout8_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.gEditFieldLabel = uilabel(app.GridLayout8_2);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.TransformasiLogPanel = uipanel(app.UIFigure);
            app.TransformasiLogPanel.Title = 'Transformasi Log';
            app.GridLayout8_3 = uigridlayout(app.TransformasiLogPanel);
            app.cEditField_2Label = uilabel(app.GridLayout8_3);
            app.cEditField_2Label.Text = 'c';
            app.cEditField_2 = uieditfield(app.GridLayout8_3, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tubes1gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end